classdef salemibot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Guess1EditField_11             matlab.ui.control.EditField
        Guess1EditField_9              matlab.ui.control.EditField
        Guess1EditField_7              matlab.ui.control.EditField
        Guess1EditField_3              matlab.ui.control.EditField
        SubmitGuessandResultButton     matlab.ui.control.Button
        NewGameButton                  matlab.ui.control.Button
        Suggestion                     matlab.ui.control.Label
        SuggestedGuessLabel            matlab.ui.control.Label
        Guess1EditField_12             matlab.ui.control.EditField
        Guess1EditField_10             matlab.ui.control.EditField
        Guess1EditField_8              matlab.ui.control.EditField
        Result2Green1Yellow0GreyLabel  matlab.ui.control.Label
        GuessLabel                     matlab.ui.control.Label
        Guess1EditField_6              matlab.ui.control.EditField
        Guess1EditField_5              matlab.ui.control.EditField
        Guess1EditField_4              matlab.ui.control.EditField
        Guess1EditField_2              matlab.ui.control.EditField
        Guess1EditField_1              matlab.ui.control.EditField
        ExampleBotLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        matchCodes % The match codes for all words and all words.
        wordstr % Strings of all words
        words % Character Arrays of all words
        wordCols
        initProb
        latestGuess
        latestResult
        suggestion
        count
        guessFields  % Places to enter your guesses
        resultFields % Places to enter results
        guessHistory
        resultHistory
        MF % Descriptio
        gameOver
    end
    
    methods (Access = private)

        
        function findGuess(app, probMat, words)
            xx = probMat .* log(1 ./ probMat);
            bits = sum(xx);
            [~, imax] = max(bits);
            app.suggestion = words(imax);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MF = load("wordleData.mat");
            app.matchCodes = app.MF.matchCodes;
            app.initProb = app.MF.initProb;
            app.words = app.MF.words;
            app.wordstr = app.MF.wordstr;
            app.guessFields = [app.Guess1EditField_1 ...
            app.Guess1EditField_2 ...
            app.Guess1EditField_3 ...          
            app.Guess1EditField_4 ...
            app.Guess1EditField_5 ...
            app.Guess1EditField_6];
            app.resultFields = [app.Guess1EditField_7 ...
            app.Guess1EditField_8 ...          
            app.Guess1EditField_9 ...
            app.Guess1EditField_10 ...
            app.Guess1EditField_11 ...
            app.Guess1EditField_12 ];
            for ii = 1:6
                app.guessFields(ii).Enable = "off";
                app.resultFields(ii).Enable ="off";
                app.guessFields(ii).HorizontalAlignment = 'center';
                app.resultFields(ii).HorizontalAlignment = 'center';

            end
            app.gameOver = true;
            app.SubmitGuessandResultButton.Enable = 'off';
        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
            app.guessFields(1).Enable = "on";
            app.resultFields(1).Enable = 'on';
            for ii = 2:6
                app.guessFields(ii).Enable = "off";
                app.resultFields(ii).Enable ="off";
            end
            for ii = 1:6
                app.guessFields(ii).Value = '';
                app.resultFields(ii).Value = '';
            end
            app.count = 1;
            app.findGuess(app.initProb, app.words)
            app.Suggestion.Text = app.suggestion;
            app.gameOver = false;
            app.SubmitGuessandResultButton.Enable = 'on';
            app.guessHistory = cell(6,1);
            app.resultHistory = cell(6,1);
        end

        % Callback function
        function Guess1EditField_1ValueChanged(app, event)

        end

        % Button pushed function: SubmitGuessandResultButton
        function SubmitGuessandResultButtonPushed(app, event)
            app.guessHistory{app.count} = lower(app.guessFields(app.count).Value);

            resultb3 = app.resultFields(app.count).Value;
            if resultb3 == "22222"
                app.Suggestion.Text = "Winner!";
                app.SubmitGuessandResultButton.Enable = 'off';
            else
                app.resultHistory{app.count} = resultb3;
                app.Suggestion.Text = getBotSuggestion(app.guessHistory, ...
                    app.resultHistory, app.wordstr, app.matchCodes);
                app.count = app.count+1;

                % result = base2dec(resultb3, 3);
                % guessRow = find(strcmp(app.wordstr,app.latestGuess));
                % newWordCols = find(app.matchCodes(guessRow,:) == result);
                % if app.count == 1
                %     app.wordCols = newWordCols;
                % else
                %     app.wordCols = intersect(app.wordCols, newWordCols);
                % end
                % app.findGuess(app.initProb(:,app.wordCols),app.words(app.wordCols))
                % app.Suggestion.Text = app.suggestion;
                % app.count = app.count + 1;
                % 
                if app.count > 6
                    app.Suggestion.Text = "Loser!";
                    app.gameOver = true;
                    app.SubmitGuessandResultButton.Enable = 'off';
                else
                    app.guessFields(app.count).Enable = 'on';
                    app.resultFields(app.count).Enable = 'on';
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ExampleBotLabel = uilabel(app.GridLayout);
            app.ExampleBotLabel.Text = 'ExampleBot';
            app.Guess1EditField_1 = uieditfield(app.GridLayout, 'text');
            app.Guess1EditField_2 = uieditfield(app.GridLayout, 'text');
            app.Guess1EditField_4 = uieditfield(app.GridLayout, 'text');
            app.Guess1EditField_5 = uieditfield(app.GridLayout, 'text');
            app.Guess1EditField_6 = uieditfield(app.GridLayout, 'text');
            app.GuessLabel = uilabel(app.GridLayout);
            app.GuessLabel.Text = 'Guess';
            app.Result2Green1Yellow0GreyLabel = uilabel(app.GridLayout);
            app.Result2Green1Yellow0GreyLabel.Text = {'Result '; '(2==Green, 1==Yellow, 0==Grey)'};
            app.Guess1EditField_8 = uieditfield(app.GridLayout, 'text');
            app.Guess1EditField_10 = uieditfield(app.GridLayout, 'text');
            app.Guess1EditField_12 = uieditfield(app.GridLayout, 'text');
            app.SuggestedGuessLabel = uilabel(app.GridLayout);
            app.SuggestedGuessLabel.Text = 'Suggested Guess';
            app.Suggestion = uilabel(app.GridLayout);
            app.Suggestion.Text = '';
            app.NewGameButton = uibutton(app.GridLayout, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
            app.SubmitGuessandResultButton = uibutton(app.GridLayout, 'push');
            app.SubmitGuessandResultButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitGuessandResultButtonPushed, true);
            app.SubmitGuessandResultButton.Text = 'Submit Guess and Result';
            app.Guess1EditField_3 = uieditfield(app.GridLayout, 'text');
            app.Guess1EditField_7 = uieditfield(app.GridLayout, 'text');
            app.Guess1EditField_9 = uieditfield(app.GridLayout, 'text');
            app.Guess1EditField_11 = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = salemibot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end